classdef Hog_Gui_temp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HogModelResultsUIFigure       matlab.ui.Figure
        SpatialPlotButtonGroup        matlab.ui.container.ButtonGroup
        NonSpatialButton              matlab.ui.control.ToggleButton
        SpatialButton                 matlab.ui.control.ToggleButton
        AnalysisTypeButtonGroup       matlab.ui.container.ButtonGroup
        MeansButton                   matlab.ui.control.ToggleButton
        ndMomentsButton               matlab.ui.control.ToggleButton
        ExtendedMomentsButton         matlab.ui.control.ToggleButton
        FSPButton                     matlab.ui.control.ToggleButton
        SpatialTypeButtonGroup        matlab.ui.container.ButtonGroup
        NonSpatialButton_2            matlab.ui.control.ToggleButton
        SpatialButton_2               matlab.ui.control.ToggleButton
        GeneTypeButtonGroup           matlab.ui.container.ButtonGroup
        STL1Button                    matlab.ui.control.ToggleButton
        CTT1Button                    matlab.ui.control.ToggleButton
        FITTINGCONDITIONSLabel        matlab.ui.control.Label
        PLOTTYPESLabel                matlab.ui.control.Label
        PlotMeansButton               matlab.ui.control.StateButton
        PlotVariancesButton           matlab.ui.control.StateButton
        PlotDistributionsButton       matlab.ui.control.StateButton
        PARAMETERSLabel               matlab.ui.control.Label
        k12EditFieldLabel             matlab.ui.control.Label
        k12EditField                  matlab.ui.control.NumericEditField
        k21aEditFieldLabel            matlab.ui.control.Label
        k21aEditField                 matlab.ui.control.NumericEditField
        k21bEditFieldLabel            matlab.ui.control.Label
        k21bEditField                 matlab.ui.control.NumericEditField
        k23EditFieldLabel             matlab.ui.control.Label
        k23EditField                  matlab.ui.control.NumericEditField
        k32EditFieldLabel             matlab.ui.control.Label
        k32EditField                  matlab.ui.control.NumericEditField
        k34EditFieldLabel             matlab.ui.control.Label
        k34EditField                  matlab.ui.control.NumericEditField
        k43EditFieldLabel             matlab.ui.control.Label
        k43EditField                  matlab.ui.control.NumericEditField
        kr1EditFieldLabel             matlab.ui.control.Label
        kr1EditField                  matlab.ui.control.NumericEditField
        kr2EditFieldLabel             matlab.ui.control.Label
        kr2EditField                  matlab.ui.control.NumericEditField
        kr3EditFieldLabel             matlab.ui.control.Label
        kr3EditField                  matlab.ui.control.NumericEditField
        kr4EditFieldLabel             matlab.ui.control.Label
        kr4EditField                  matlab.ui.control.NumericEditField
        timeoffsetEditFieldLabel      matlab.ui.control.Label
        timeoffsetEditField           matlab.ui.control.NumericEditField
        transportEditFieldLabel       matlab.ui.control.Label
        transportEditField            matlab.ui.control.NumericEditField
        nucleardegradationEditFieldLabel  matlab.ui.control.Label
        nucleardegradationEditField   matlab.ui.control.NumericEditField
        degradationEditFieldLabel     matlab.ui.control.Label
        degradationEditField          matlab.ui.control.NumericEditField
        MakeFiguresButton             matlab.ui.control.Button
        FigEditFieldLabel             matlab.ui.control.Label
        FigEditField                  matlab.ui.control.NumericEditField
        FigEditField_2Label           matlab.ui.control.Label
        FigEditField_2                matlab.ui.control.NumericEditField
        FigEditField_3Label           matlab.ui.control.Label
        FigEditField_3                matlab.ui.control.NumericEditField
        mRNAelongationEditFieldLabel  matlab.ui.control.Label
        mRNAelongationEditField       matlab.ui.control.NumericEditField
        NascentmRNAButton             matlab.ui.control.StateButton
        FigEditField_4Label           matlab.ui.control.Label
        FigEditField_4                matlab.ui.control.NumericEditField
        CloseFiguresButton            matlab.ui.control.Button
        UPDATEMODELButton             matlab.ui.control.Button
        OpenGUIforManuscriptFiguresButton  matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        MOD=Hog_Model; % Description
        Moments = [];
        Distributions = [];
        RNAdata = []; % Description
    end
    

    methods (Access = private)

        % Selection changed function: AnalysisTypeButtonGroup
        function AnalysisTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AnalysisTypeButtonGroup.SelectedObject;
            Rest_GUI_Parameters(app);
        end

        % Selection changed function: GeneTypeButtonGroup
        function GeneTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.GeneTypeButtonGroup.SelectedObject;
            Rest_GUI_Parameters(app);
        end

        % Button pushed function: MakeFiguresButton
        function MakeFiguresButtonPushed(app, event)
            Solve_GUI(app);
        end

        % Selection changed function: SpatialTypeButtonGroup
        function SpatialTypeButtonGroupSelectionChanged(app, event)
            Rest_GUI_Parameters(app);
            if app.SpatialTypeButtonGroup.Buttons(1).Value==1
                app.SpatialPlotButtonGroup.Buttons(1).Value=1;
            elseif app.SpatialTypeButtonGroup.Buttons(2).Value==1
                app.SpatialPlotButtonGroup.Buttons(2).Value=1;
            end
        end

        % Button pushed function: CloseFiguresButton
        function CloseFiguresButtonPushed(app, event)
            close all;
        end

        % Button pushed function: UPDATEMODELButton
        function UPDATEMODELButtonPushed(app, event)
            Reset_GUI(app);
            disp('Model has been updated');
        end

        % Value changed function: OpenGUIforManuscriptFiguresButton
        function OpenGUIforManuscriptFiguresButtonValueChanged(app, event)
            Make_Figures_GUI;
        end

        % Selection changed function: SpatialPlotButtonGroup
        function SpatialPlotButtonGroupSelectionChanged(app, event)
            if app.SpatialPlotButtonGroup.Buttons(2).Value==1&&...
                 app.SpatialTypeButtonGroup.Buttons(1).Value==1
                app.SpatialPlotButtonGroup.Buttons(2).Value=0;
                app.SpatialPlotButtonGroup.Buttons(1).Value=1;
                msgbox('Cannot make spatial plot for non-spatial model');               
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HogModelResultsUIFigure = uifigure;
            app.HogModelResultsUIFigure.Name = 'Hog Model Results';
            app.SpatialPlotButtonGroup = uibuttongroup(app.HogModelResultsUIFigure);
            app.SpatialPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpatialPlotButtonGroupSelectionChanged, true);
            app.SpatialPlotButtonGroup.Title = 'Spatial Plot';
            app.NonSpatialButton = uitogglebutton(app.SpatialPlotButtonGroup);
            app.NonSpatialButton.Text = 'NonSpatial';
            app.NonSpatialButton.Value = true;
            app.SpatialButton = uitogglebutton(app.SpatialPlotButtonGroup);
            app.SpatialButton.Text = 'Spatial';
            app.AnalysisTypeButtonGroup = uibuttongroup(app.HogModelResultsUIFigure);
            app.AnalysisTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisTypeButtonGroupSelectionChanged, true);
            app.AnalysisTypeButtonGroup.Title = 'Analysis Type';
            app.MeansButton = uitogglebutton(app.AnalysisTypeButtonGroup);
            app.MeansButton.Text = 'Means';
            app.ndMomentsButton = uitogglebutton(app.AnalysisTypeButtonGroup);
            app.ndMomentsButton.Text = '2nd Moments';
            app.ExtendedMomentsButton = uitogglebutton(app.AnalysisTypeButtonGroup);
            app.ExtendedMomentsButton.Text = 'Extended Moments';
            app.FSPButton = uitogglebutton(app.AnalysisTypeButtonGroup);
            app.FSPButton.Text = 'FSP';
            app.FSPButton.Value = true;
            app.SpatialTypeButtonGroup = uibuttongroup(app.HogModelResultsUIFigure);
            app.SpatialTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpatialTypeButtonGroupSelectionChanged, true);
            app.SpatialTypeButtonGroup.Title = 'Spatial Type';
            app.NonSpatialButton_2 = uitogglebutton(app.SpatialTypeButtonGroup);
            app.NonSpatialButton_2.Text = 'NonSpatial';
            app.NonSpatialButton_2.Value = true;
            app.SpatialButton_2 = uitogglebutton(app.SpatialTypeButtonGroup);
            app.SpatialButton_2.Text = 'Spatial';
            app.GeneTypeButtonGroup = uibuttongroup(app.HogModelResultsUIFigure);
            app.GeneTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GeneTypeButtonGroupSelectionChanged, true);
            app.GeneTypeButtonGroup.Title = 'Gene Type';
            app.STL1Button = uitogglebutton(app.GeneTypeButtonGroup);
            app.STL1Button.Text = 'STL1';
            app.STL1Button.Value = true;
            app.CTT1Button = uitogglebutton(app.GeneTypeButtonGroup);
            app.CTT1Button.Text = 'CTT1';
            app.FITTINGCONDITIONSLabel = uilabel(app.HogModelResultsUIFigure);
            app.FITTINGCONDITIONSLabel.Text = 'FITTING CONDITIONS';
            app.PLOTTYPESLabel = uilabel(app.HogModelResultsUIFigure);
            app.PLOTTYPESLabel.Text = 'PLOT TYPES';
            app.PlotMeansButton = uibutton(app.HogModelResultsUIFigure, 'state');
            app.PlotMeansButton.Text = 'Plot Means';
            app.PlotMeansButton.Value = true;
            app.PlotVariancesButton = uibutton(app.HogModelResultsUIFigure, 'state');
            app.PlotVariancesButton.Text = 'Plot Variances';
            app.PlotVariancesButton.Value = true;
            app.PlotDistributionsButton = uibutton(app.HogModelResultsUIFigure, 'state');
            app.PlotDistributionsButton.Text = 'Plot Distributions';
            app.PlotDistributionsButton.Value = true;
            app.PARAMETERSLabel = uilabel(app.HogModelResultsUIFigure);
            app.PARAMETERSLabel.Text = 'PARAMETERS';
            app.k12EditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.k12EditFieldLabel.Text = 'k12';
            app.k12EditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.k12EditField.Limits = [0 Inf];
            app.k21aEditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.k21aEditFieldLabel.Text = 'k21a';
            app.k21aEditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.k21bEditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.k21bEditFieldLabel.Text = 'k21b';
            app.k21bEditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.k23EditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.k23EditFieldLabel.Text = 'k23';
            app.k23EditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.k23EditField.Limits = [0 Inf];
            app.k32EditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.k32EditFieldLabel.Text = 'k32';
            app.k32EditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.k32EditField.Limits = [0 Inf];
            app.k34EditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.k34EditFieldLabel.Text = 'k34';
            app.k34EditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.k34EditField.Limits = [0 Inf];
            app.k43EditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.k43EditFieldLabel.Text = 'k43';
            app.k43EditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.k43EditField.Limits = [0 Inf];
            app.kr1EditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.kr1EditFieldLabel.Text = 'kr1';
            app.kr1EditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.kr1EditField.Limits = [0 Inf];
            app.kr2EditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.kr2EditFieldLabel.Text = 'kr2';
            app.kr2EditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.kr2EditField.Limits = [0 Inf];
            app.kr3EditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.kr3EditFieldLabel.Text = 'kr3';
            app.kr3EditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.kr3EditField.Limits = [0 Inf];
            app.kr4EditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.kr4EditFieldLabel.Text = 'kr4';
            app.kr4EditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.kr4EditField.Limits = [0 Inf];
            app.timeoffsetEditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.timeoffsetEditFieldLabel.Text = 'time offset';
            app.timeoffsetEditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.timeoffsetEditField.Limits = [0 Inf];
            app.transportEditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.transportEditFieldLabel.Text = 'transport';
            app.transportEditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.transportEditField.Limits = [0 Inf];
            app.nucleardegradationEditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.nucleardegradationEditFieldLabel.Text = 'nuclear degradation';
            app.nucleardegradationEditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.nucleardegradationEditField.Limits = [0 Inf];
            app.degradationEditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.degradationEditFieldLabel.Text = 'degradation';
            app.degradationEditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.degradationEditField.Limits = [0 Inf];
            app.MakeFiguresButton = uibutton(app.HogModelResultsUIFigure, 'push');
            app.MakeFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @MakeFiguresButtonPushed, true);
            app.MakeFiguresButton.Text = 'Make Figure(s)';
            app.FigEditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.FigEditFieldLabel.Text = 'Fig#';
            app.FigEditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.FigEditField.Limits = [1 100];
            app.FigEditField.RoundFractionalValues = 'on';
            app.FigEditField.Value = 1;
            app.FigEditField_2Label = uilabel(app.HogModelResultsUIFigure);
            app.FigEditField_2Label.Text = 'Fig#';
            app.FigEditField_2 = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.FigEditField_2.Limits = [101 201];
            app.FigEditField_2.RoundFractionalValues = 'on';
            app.FigEditField_2.Value = 101;
            app.FigEditField_3Label = uilabel(app.HogModelResultsUIFigure);
            app.FigEditField_3Label.Text = 'Fig#';
            app.FigEditField_3 = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.FigEditField_3.Limits = [201 301];
            app.FigEditField_3.RoundFractionalValues = 'on';
            app.FigEditField_3.Value = 201;
            app.mRNAelongationEditFieldLabel = uilabel(app.HogModelResultsUIFigure);
            app.mRNAelongationEditFieldLabel.Text = 'mRNA elongation ';
            app.mRNAelongationEditField = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.mRNAelongationEditField.Limits = [0 Inf];
            app.NascentmRNAButton = uibutton(app.HogModelResultsUIFigure, 'state');
            app.NascentmRNAButton.Text = 'Nascent mRNA';
            app.FigEditField_4Label = uilabel(app.HogModelResultsUIFigure);
            app.FigEditField_4Label.Text = 'Fig#';
            app.FigEditField_4 = uieditfield(app.HogModelResultsUIFigure, 'numeric');
            app.FigEditField_4.Limits = [301 401];
            app.FigEditField_4.RoundFractionalValues = 'on';
            app.FigEditField_4.Value = 301;
            app.CloseFiguresButton = uibutton(app.HogModelResultsUIFigure, 'push');
            app.CloseFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFiguresButtonPushed, true);
            app.CloseFiguresButton.Text = 'Close Figures';
            app.UPDATEMODELButton = uibutton(app.HogModelResultsUIFigure, 'push');
            app.UPDATEMODELButton.ButtonPushedFcn = createCallbackFcn(app, @UPDATEMODELButtonPushed, true);
            app.UPDATEMODELButton.Text = 'UPDATE MODEL';
            app.OpenGUIforManuscriptFiguresButton = uibutton(app.HogModelResultsUIFigure, 'state');
            app.OpenGUIforManuscriptFiguresButton.ValueChangedFcn = createCallbackFcn(app, @OpenGUIforManuscriptFiguresButtonValueChanged, true);
            app.OpenGUIforManuscriptFiguresButton.Text = 'Open GUI for Manuscript Figures';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Hog_Gui_temp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HogModelResultsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HogModelResultsUIFigure)
        end
    end
end