classdef Make_Figures_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Figure2Button      matlab.ui.control.Button
        Figure3Button      matlab.ui.control.Button
        Figure4Button      matlab.ui.control.Button
        RegenerateUnformattedFiguresButton  matlab.ui.control.Button
        TextArea           matlab.ui.control.TextArea
        FigureS1Button     matlab.ui.control.Button
        FigureS2Button     matlab.ui.control.Button
        FigureS3Button     matlab.ui.control.Button
        FigureS4Button     matlab.ui.control.Button
        FigureS5Button     matlab.ui.control.Button
        FigureS6Button     matlab.ui.control.Button
        FigureS7Button     matlab.ui.control.Button
        FigureS8Button     matlab.ui.control.Button
        FigureS9S10Button  matlab.ui.control.Button
        FigureS11Button    matlab.ui.control.Button
        FigureS12Button    matlab.ui.control.Button
        MakeAllButton      matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: Figure2Button
        function Figure2ButtonPushed(app, event)
            close all
            Make_Panels_For_Figure_2('STL1')
            saveas(21,'Final_Figures/Fig2/2A','fig');saveas(21,'Final_Figures/Fig2/2A','epsc')
            saveas(22,'Final_Figures/Fig2/2B','fig');saveas(22,'Final_Figures/Fig2/2B','epsc')
            saveas(23,'Final_Figures/Fig2/2C','fig');saveas(23,'Final_Figures/Fig2/2C','epsc')
            saveas(24,'Final_Figures/Fig2/2D','fig');saveas(24,'Final_Figures/Fig2/2D','epsc')
            saveas(121,'Final_Figures/SupMat/S10/S10AB','fig');saveas(121,'Final_Figures/SupMat/S10/S10AB','epsc')
            saveas(122,'Final_Figures/SupMat/S10/S10CD','fig');saveas(122,'Final_Figures/SupMat/S10/S10CD','epsc')

        end

        % Button pushed function: RegenerateUnformattedFiguresButton
        function RegenerateUnformattedFiguresButtonPushed(app, event)
            close all
            warning('off','all')
            delete Figures/*.fig
            delete Figures/0_Main_Figures/*.fig
            delete Figures/1_TS_Figures/*.fig
            delete Final_Figures/*/*.fig
            delete Final_Figures/*/*.eps
            delete Final_Figures/*/*.jpg  
            Update_Analyses
            
            Update_MCMC_Plots
            Update_Plots
            Plot_TS_Theory
        end

        % Button pushed function: FigureS1Button
        function FigureS1ButtonPushed(app, event)
            close all
            path(path,'Hog_Data/Hog1p_Data_Fitting')
            figure(51);Main_ID_Match_Hog1
            saveas(51,'Final_Figures/SupMat/S1/S1','fig');saveas(51,'Final_Figures/SupMat/S1/S1','epsc')
        end

        % Button pushed function: Figure3Button
        function Figure3ButtonPushed(app, event)
            close all          
            Fig_3_PanelsEFG('STL1')           
            Make_Panels_For_Figure_3('STL1',0.2)
            saveas(31,'Final_Figures/Fig3/3ABC','fig');saveas(31,'Final_Figures/Fig3/3ABC','epsc')
            saveas(32,'Final_Figures/Fig3/3D','fig');saveas(32,'Final_Figures/Fig3/3D','epsc')
            saveas(11,'Final_Figures/Fig3/3E','fig');saveas(11,'Final_Figures/Fig3/3E','epsc')
            saveas(12,'Final_Figures/Fig3/3F','fig');saveas(12,'Final_Figures/Fig3/3F','epsc')
            saveas(14,'Final_Figures/Fig3/3G','fig');saveas(14,'Final_Figures/Fig3/3G','epsc')
        end

        % Button pushed function: Figure4Button
        function Figure4ButtonPushed(app, event)
            close all
            Make_Panels_For_Figure_4('STL1')
            saveas(41,'Final_Figures/Fig4/4A','fig');saveas(41,'Final_Figures/Fig4/4A','epsc')
            saveas(42,'Final_Figures/Fig4/4B','fig');saveas(42,'Final_Figures/Fig4/4B','epsc')
            saveas(43,'Final_Figures/Fig4/4C','fig');saveas(43,'Final_Figures/Fig4/4C','epsc')
            saveas(44,'Final_Figures/Fig4/4D','fig');saveas(44,'Final_Figures/Fig4/4D','epsc')
            saveas(45,'Final_Figures/Fig4/4E','fig');saveas(45,'Final_Figures/Fig4/4E','epsc')
            saveas(46,'Final_Figures/Fig4/4F','fig');saveas(46,'Final_Figures/Fig4/4F','epsc')
            saveas(47,'Final_Figures/Fig4/4G','fig');saveas(47,'Final_Figures/Fig4/4G','epsc')
            saveas(48,'Final_Figures/Fig4/4H','fig');saveas(48,'Final_Figures/Fig4/4H','epsc')            
        end

        % Button pushed function: FigureS2Button
        function FigureS2ButtonPushed(app, event)
            close all
            uiopen('Figures/STL1_NonSpatial_Fit_Means_Plot_Marg_Dists_0p2.fig',1)
            uiopen('Figures/STL1_NonSpatial_Fit_Means_Plot_Marg_Dists_0p4.fig',1)
            uiopen('Figures/STL1_NonSpatial_Fit_Moments_Corrected_Plot_Marg_Dists_0p2.fig',1)
            uiopen('Figures/STL1_NonSpatial_Fit_Moments_Corrected_Plot_Marg_Dists_0p4.fig',1)
            uiopen('Figures/STL1_NonSpatial_Fit_Distributions_Plot_Marg_Dists_0p2.fig',1)
            uiopen('Figures/STL1_NonSpatial_Fit_Distributions_Plot_Marg_Dists_0p4.fig',1)
            uiopen('Figures/STL1_NonSpatial_Fit_Moments_4_Plot_Marg_Dists_0p2.fig',1)
            uiopen('Figures/STL1_NonSpatial_Fit_Moments_4_Plot_Marg_Dists_0p4.fig',1)
            saveas(1,'Final_Figures/SupMat/S2/S2A','fig');saveas(1,'Final_Figures/SupMat/S2/S2A','epsc')
            saveas(2,'Final_Figures/SupMat/S2/S2B','fig');saveas(2,'Final_Figures/SupMat/S2/S2B','epsc')
            saveas(3,'Final_Figures/SupMat/S2/S2C','fig');saveas(3,'Final_Figures/SupMat/S2/S2C','epsc')
            saveas(4,'Final_Figures/SupMat/S2/S2D','fig');saveas(4,'Final_Figures/SupMat/S2/S2D','epsc')
            saveas(5,'Final_Figures/SupMat/S2/S2E','fig');saveas(5,'Final_Figures/SupMat/S2/S2E','epsc')
            saveas(6,'Final_Figures/SupMat/S2/S2F','fig');saveas(6,'Final_Figures/SupMat/S2/S2F','epsc')
            saveas(7,'Final_Figures/4th_Moments/S2E','fig');saveas(7,'Final_Figures/4th_Moments/S2E','epsc')
            saveas(8,'Final_Figures/4th_Moments/S2F','fig');saveas(8,'Final_Figures/4th_Moments/S2F','epsc')

        end

        % Button pushed function: FigureS3Button
        function FigureS3ButtonPushed(app, event)
            close all
            uiopen('Figures/CTT1_NonSpatial_Fit_Means_Plot_Marg_Dists_0p2.fig',1)
            uiopen('Figures/CTT1_NonSpatial_Fit_Means_Plot_Marg_Dists_0p4.fig',1)
            uiopen('Figures/CTT1_NonSpatial_Fit_Moments_Corrected_Plot_Marg_Dists_0p2.fig',1)
            uiopen('Figures/CTT1_NonSpatial_Fit_Moments_Corrected_Plot_Marg_Dists_0p4.fig',1)
            uiopen('Figures/CTT1_NonSpatial_Fit_Distributions_Plot_Marg_Dists_0p2.fig',1)
            uiopen('Figures/CTT1_NonSpatial_Fit_Distributions_Plot_Marg_Dists_0p4.fig',1)
            uiopen('Figures/CTT1_NonSpatial_Fit_Moments_4_Plot_Marg_Dists_0p2.fig',1)
            uiopen('Figures/CTT1_NonSpatial_Fit_Moments_4_Plot_Marg_Dists_0p4.fig',1)
            saveas(1,'Final_Figures/SupMat/S3/S3A','fig');saveas(1,'Final_Figures/SupMat/S3/S3A','epsc')
            saveas(2,'Final_Figures/SupMat/S3/S3B','fig');saveas(2,'Final_Figures/SupMat/S3/S3B','epsc')
            saveas(3,'Final_Figures/SupMat/S3/S3C','fig');saveas(3,'Final_Figures/SupMat/S3/S3C','epsc')
            saveas(4,'Final_Figures/SupMat/S3/S3D','fig');saveas(4,'Final_Figures/SupMat/S3/S3D','epsc')
            saveas(5,'Final_Figures/SupMat/S3/S3G','fig');saveas(5,'Final_Figures/SupMat/S3/S3G','epsc')
            saveas(6,'Final_Figures/SupMat/S3/S3H','fig');saveas(6,'Final_Figures/SupMat/S3/S3H','epsc')
            saveas(7,'Final_Figures/SupMat/S3/S3E','fig');saveas(7,'Final_Figures/SupMat/S3/S3E','epsc')
            saveas(8,'Final_Figures/SupMat/S3/S3F','fig');saveas(8,'Final_Figures/SupMat/S3/S3F','epsc')

        end

        % Button pushed function: FigureS4Button
        function FigureS4ButtonPushed(app, event)
            close all
            uiopen('Figures/STL1_Spat_Dists_Dat_0p2.fig',1)
            uiopen('Figures/STL1_Spat_Dists_Dat_0p4.fig',1)
            uiopen('Figures/STL1_Spatial_Fit_Distributions_Plot_Spat_Dists_Mod_0p2.fig',1)
            uiopen('Figures/STL1_Spatial_Fit_Distributions_Plot_Spat_Dists_Mod_0p4.fig',1)
            uiopen('Figures/STL1_Spatial_Fit_Moments_Corrected_Plot_Spat_Dists_Mod_0p2.fig',1)
            uiopen('Figures/STL1_Spatial_Fit_Moments_Corrected_Plot_Spat_Dists_Mod_0p4.fig',1)
            uiopen('Figures/STL1_Spatial_Fit_Means_Plot_Spat_Dists_Mod_0p2.fig',1)
            uiopen('Figures/STL1_Spatial_Fit_Means_Plot_Spat_Dists_Mod_0p4.fig',1)
            uiopen('Figures/STL1_Spatial_Fit_Moments_4_Plot_Spat_Dists_Mod_0p2.fig',1)
            uiopen('Figures/STL1_Spatial_Fit_Moments_4_Plot_Spat_Dists_Mod_0p4.fig',1)
            saveas(1,'Final_Figures/SupMat/S4/S4A','fig');saveas(1,'Final_Figures/SupMat/S4/S4A','epsc')
            saveas(2,'Final_Figures/SupMat/S4/S4B','fig');saveas(2,'Final_Figures/SupMat/S4/S4B','epsc')
            saveas(3,'Final_Figures/SupMat/S4/S4C','fig');saveas(3,'Final_Figures/SupMat/S4/S4C','epsc')
            saveas(4,'Final_Figures/SupMat/S4/S4D','fig');saveas(4,'Final_Figures/SupMat/S4/S4D','epsc')
            saveas(5,'Final_Figures/SupMat/S4/S4E','fig');saveas(5,'Final_Figures/SupMat/S4/S4E','epsc')
            saveas(6,'Final_Figures/SupMat/S4/S4F','fig');saveas(6,'Final_Figures/SupMat/S4/S4F','epsc')
            saveas(7,'Final_Figures/SupMat/S4/S4G','fig');saveas(7,'Final_Figures/SupMat/S4/S4G','epsc')
            saveas(8,'Final_Figures/SupMat/S4/S4H','fig');saveas(8,'Final_Figures/SupMat/S4/S4H','epsc')
            saveas(9,'Final_Figures/SupMat/S4/S4I-Extra','fig');saveas(9,'Final_Figures/SupMat/S4/S4I-Extra','epsc')
            saveas(10,'Final_Figures/SupMat/S4/S4J-Extra','fig');saveas(10,'Final_Figures/SupMat/S4/S4J-Extra','epsc')
            
        end

        % Button pushed function: FigureS5Button
        function FigureS5ButtonPushed(app, event)
            close all
            uiopen('Figures/CTT1_Spat_Dists_Dat_0p2.fig',1)
            uiopen('Figures/CTT1_Spat_Dists_Dat_0p4.fig',1)
            uiopen('Figures/CTT1_Spatial_Fit_Distributions_Plot_Spat_Dists_Mod_0p2.fig',1)
            uiopen('Figures/CTT1_Spatial_Fit_Distributions_Plot_Spat_Dists_Mod_0p4.fig',1)
            uiopen('Figures/CTT1_Spatial_Fit_Moments_Corrected_Plot_Spat_Dists_Mod_0p2.fig',1)
            uiopen('Figures/CTT1_Spatial_Fit_Moments_Corrected_Plot_Spat_Dists_Mod_0p4.fig',1)
            uiopen('Figures/CTT1_Spatial_Fit_Means_Plot_Spat_Dists_Mod_0p2.fig',1)
            uiopen('Figures/CTT1_Spatial_Fit_Means_Plot_Spat_Dists_Mod_0p4.fig',1)
            uiopen('Figures/CTT1_Spatial_Fit_Moments_4_Plot_Spat_Dists_Mod_0p2.fig',1)
            uiopen('Figures/CTT1_Spatial_Fit_Moments_4_Plot_Spat_Dists_Mod_0p4.fig',1)
            saveas(1,'Final_Figures/SupMat/S5/S5A','fig');saveas(1,'Final_Figures/SupMat/S5/S5A','epsc')
            saveas(2,'Final_Figures/SupMat/S5/S5B','fig');saveas(2,'Final_Figures/SupMat/S5/S5B','epsc')
            saveas(3,'Final_Figures/SupMat/S5/S5C','fig');saveas(3,'Final_Figures/SupMat/S5/S5C','epsc')
            saveas(4,'Final_Figures/SupMat/S5/S5D','fig');saveas(4,'Final_Figures/SupMat/S5/S5D','epsc')
            saveas(5,'Final_Figures/SupMat/S5/S5E','fig');saveas(5,'Final_Figures/SupMat/S5/S5E','epsc')
            saveas(6,'Final_Figures/SupMat/S5/S5F','fig');saveas(6,'Final_Figures/SupMat/S5/S5F','epsc')
            saveas(7,'Final_Figures/SupMat/S5/S5G','fig');saveas(7,'Final_Figures/SupMat/S5/S5G','epsc')
            saveas(8,'Final_Figures/SupMat/S5/S5H','fig');saveas(8,'Final_Figures/SupMat/S5/S5H','epsc')
            saveas(9,'Final_Figures/SupMat/S5/S5I-Extra','fig');saveas(9,'Final_Figures/SupMat/S5/S5I-Extra','epsc')
            saveas(10,'Final_Figures/SupMat/S5/S5J-Extra','fig');saveas(10,'Final_Figures/SupMat/S5/S5J-Extra','epsc')

        end

        % Button pushed function: FigureS6Button
        function FigureS6ButtonPushed(app, event)
  
        end

        % Button pushed function: FigureS7Button
        function FigureS7ButtonPushed(app, event)
            close all
            Make_Panels_For_Figure_3('STL1',0.4);
            saveas(31,'Final_Figures/SupMat/S7/S7ABC','fig');saveas(31,'Final_Figures/SupMat/S7/S7ABC','epsc')
            saveas(32,'Final_Figures/SupMat/S7/S7D','fig');saveas(32,'Final_Figures/SupMat/S7/S7D','epsc')
            
            % S10.2E-H CTT1 0.2M NaCl
            close all
            Make_Panels_For_Figure_3('CTT1',0.2);
            saveas(31,'Final_Figures/SupMat/S7/S7EFG','fig');saveas(31,'Final_Figures/SupMat/S7/S7EFG','epsc')
            saveas(32,'Final_Figures/SupMat/S7/S7H','fig');saveas(32,'Final_Figures/SupMat/S7/S7H','epsc')
            
            % S10.3I-L CTT1 0.4M NaCl
            close all
            Make_Panels_For_Figure_3('CTT1',0.4);
            saveas(31,'Final_Figures/SupMat/S7/S7IJK','fig');saveas(31,'Final_Figures/SupMat/S7/S7IJK','epsc')
            saveas(32,'Final_Figures/SupMat/S7/S7L','fig');saveas(32,'Final_Figures/SupMat/S7/S7L','epsc')
        end

        % Button pushed function: FigureS8Button
        function FigureS8ButtonPushed(app, event)
            close all
            Make_SI_Fig_12
            saveas(100,'Final_Figures/SupMat/S8/S8','fig');saveas(100,'Final_Figures/SupMat/S8/S8','epsc')
        end

        % Button pushed function: FigureS9S10Button
        function FigureS9S10ButtonPushed(app, event)
            close all
            Make_Biase_and_Uncertainty_PColors
            saveas(12,'Final_Figures/SupMat/S9/S9A','fig');saveas(12,'Final_Figures/SupMat/S9/S9A','epsc')
            saveas(17,'Final_Figures/SupMat/S9/S9B','fig');saveas(17,'Final_Figures/SupMat/S9/S9B','epsc')
            saveas(13,'Final_Figures/SupMat/S9/S9C','fig');saveas(13,'Final_Figures/SupMat/S9/S9C','epsc')
            saveas(18,'Final_Figures/SupMat/S9/S9D','fig');saveas(18,'Final_Figures/SupMat/S9/S9D','epsc')
            saveas(14,'Final_Figures/SupMat/S9/S9G','fig');saveas(14,'Final_Figures/SupMat/S9/S9G','epsc')
            saveas(19,'Final_Figures/SupMat/S9/S9H','fig');saveas(19,'Final_Figures/SupMat/S9/S9H','epsc')
            
            saveas(2,'Final_Figures/SupMat/S10/S10A','fig');saveas(2,'Final_Figures/SupMat/S10/S10A','epsc')
            saveas(7,'Final_Figures/SupMat/S10/S10B','fig');saveas(7,'Final_Figures/SupMat/S10/S10B','epsc')
            saveas(3,'Final_Figures/SupMat/S10/S10C','fig');saveas(3,'Final_Figures/SupMat/S10/S10C','epsc')
            saveas(8,'Final_Figures/SupMat/S10/S10D','fig');saveas(8,'Final_Figures/SupMat/S10/S10D','epsc')
            saveas(4,'Final_Figures/SupMat/S10/S10G','fig');saveas(4,'Final_Figures/SupMat/S10/S10G','epsc')
            saveas(9,'Final_Figures/SupMat/S10/S10H','fig');saveas(9,'Final_Figures/SupMat/S10/S10H','epsc')
            
            saveas(5,'Final_Figures/SupMat/S10/S10E','fig');saveas(5,'Final_Figures/4th_Moments/S10E','epsc')
            saveas(10,'Final_Figures/SupMat/S10/S10F','fig');saveas(10,'Final_Figures/4th_Moments/S10F','epsc')
            saveas(15,'Final_Figures/SupMat/S9/S9E','fig');saveas(15,'Final_Figures/SupMat/S9/S9E','epsc')
            saveas(20,'Final_Figures/SupMat/S9/S9F','fig');saveas(20,'Final_Figures/SupMat/S9/S9F','epsc')
            
            saveas(6,'Final_Figures/SupMat/S10/S10I-Extra','fig');saveas(6,'Final_Figures/SupMat/S10/S10I-Extra','epsc')
            saveas(11,'Final_Figures/SupMat/S10/S10J-Extra','fig');saveas(11,'Final_Figures/SupMat/S10/S10J-Extra','epsc')
            saveas(16,'Final_Figures/SupMat/S9/S9I-Extra','fig');saveas(16,'Final_Figures/SupMat/S9/S9I-Extra','epsc')
            saveas(21,'Final_Figures/SupMat/S9/S9J-Extra','fig');saveas(21,'Final_Figures/SupMat/S9/S9J-Extra','epsc')

        end

        % Button pushed function: FigureS11Button
        function FigureS11ButtonPushed(app, event)
            close all
            Make_Panels_For_Figure_4('CTT1')
            saveas(41,'Final_Figures/SupMat/S11/S11A','fig');saveas(41,'Final_Figures/SupMat/S11/S11A','epsc')
            saveas(42,'Final_Figures/SupMat/S11/S11B','fig');saveas(42,'Final_Figures/SupMat/S11/S11B','epsc')
            saveas(43,'Final_Figures/SupMat/S11/S11C','fig');saveas(43,'Final_Figures/SupMat/S11/S11C','epsc')
            saveas(44,'Final_Figures/SupMat/S11/S11D','fig');saveas(44,'Final_Figures/SupMat/S11/S11D','epsc')
            saveas(45,'Final_Figures/SupMat/S11/S11E','fig');saveas(45,'Final_Figures/SupMat/S11/S11E','epsc')
            saveas(46,'Final_Figures/SupMat/S11/S11F','fig');saveas(46,'Final_Figures/SupMat/S11/S11F','epsc')
            saveas(47,'Final_Figures/SupMat/S11/S11G','fig');saveas(47,'Final_Figures/SupMat/S11/S11G','epsc')
            saveas(48,'Final_Figures/SupMat/S11/S11H','fig');saveas(48,'Final_Figures/SupMat/S11/S11H','epsc')
        end

        % Button pushed function: FigureS12Button
        function FigureS12ButtonPushed(app, event)
            close all
            Compare_FSP_and_SSA_TS
            saveas(15,'Final_Figures/SupMat/S12/S12A','fig');saveas(15,'Final_Figures/SupMat/S12/S12A','epsc')
            saveas(16,'Final_Figures/SupMat/S12/S12B','fig');saveas(16,'Final_Figures/SupMat/S12/S12B','epsc')
            saveas(115,'Final_Figures/SupMat/S12/S12C','fig');saveas(115,'Final_Figures/SupMat/S12/S12C','epsc')
            saveas(116,'Final_Figures/SupMat/S12/S12D','fig');saveas(116,'Final_Figures/SupMat/S12/S12D','epsc')
        end

        % Button pushed function: MakeAllButton
        function MakeAllButtonPushed(app, event)
%             Figure2ButtonPushed(app)
%             Figure3ButtonPushed(app)
%             Figure4ButtonPushed(app)
%             FigureS1ButtonPushed(app)
%             FigureS2ButtonPushed(app)
%             FigureS3ButtonPushed(app)
%             FigureS4ButtonPushed(app)
%             FigureS5ButtonPushed(app)
%             FigureS6ButtonPushed(app)
%             FigureS7ButtonPushed(app)
%             FigureS8ButtonPushed(app)
            FigureS9S10ButtonPushed(app)
            FigureS11ButtonPushed(app)
            FigureS12ButtonPushed(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Figure2Button = uibutton(app.UIFigure, 'push');
            app.Figure2Button.ButtonPushedFcn = createCallbackFcn(app, @Figure2ButtonPushed, true);
            app.Figure2Button.Text = 'Figure 2';
            app.Figure3Button = uibutton(app.UIFigure, 'push');
            app.Figure3Button.ButtonPushedFcn = createCallbackFcn(app, @Figure3ButtonPushed, true);
            app.Figure3Button.Text = 'Figure 3';
            app.Figure4Button = uibutton(app.UIFigure, 'push');
            app.Figure4Button.ButtonPushedFcn = createCallbackFcn(app, @Figure4ButtonPushed, true);
            app.Figure4Button.Text = 'Figure 4';
            app.RegenerateUnformattedFiguresButton = uibutton(app.UIFigure, 'push');
            app.RegenerateUnformattedFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @RegenerateUnformattedFiguresButtonPushed, true);
            app.RegenerateUnformattedFiguresButton.Text = 'Regenerate Unformatted Figures';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'This button will run codes to recompute all analysis combinations and then regenerate all un-formatted versions of all figures (including a large number not included in the manuscript). These codes will take a considerable amount of time to run (perhaps several hours).  The codes have been pre-run, and all generated figures should already be included in your original download in the folder “Figures”.'};
            app.FigureS1Button = uibutton(app.UIFigure, 'push');
            app.FigureS1Button.ButtonPushedFcn = createCallbackFcn(app, @FigureS1ButtonPushed, true);
            app.FigureS1Button.Text = 'Figure S1';
            app.FigureS2Button = uibutton(app.UIFigure, 'push');
            app.FigureS2Button.ButtonPushedFcn = createCallbackFcn(app, @FigureS2ButtonPushed, true);
            app.FigureS2Button.Text = 'Figure S2';
            app.FigureS3Button = uibutton(app.UIFigure, 'push');
            app.FigureS3Button.ButtonPushedFcn = createCallbackFcn(app, @FigureS3ButtonPushed, true);
            app.FigureS3Button.Text = 'Figure S3';
            app.FigureS4Button = uibutton(app.UIFigure, 'push');
            app.FigureS4Button.ButtonPushedFcn = createCallbackFcn(app, @FigureS4ButtonPushed, true);
            app.FigureS4Button.Text = 'Figure S4';
            app.FigureS5Button = uibutton(app.UIFigure, 'push');
            app.FigureS5Button.ButtonPushedFcn = createCallbackFcn(app, @FigureS5ButtonPushed, true);
            app.FigureS5Button.Text = 'Figure S5';
            app.FigureS6Button = uibutton(app.UIFigure, 'push');
            app.FigureS6Button.ButtonPushedFcn = createCallbackFcn(app, @FigureS6ButtonPushed, true);
            app.FigureS6Button.Text = 'Figure S6';
            app.FigureS7Button = uibutton(app.UIFigure, 'push');
            app.FigureS7Button.ButtonPushedFcn = createCallbackFcn(app, @FigureS7ButtonPushed, true);
            app.FigureS7Button.Text = 'Figure S7';
            app.FigureS8Button = uibutton(app.UIFigure, 'push');
            app.FigureS8Button.ButtonPushedFcn = createCallbackFcn(app, @FigureS8ButtonPushed, true);
            app.FigureS8Button.Text = 'Figure S8';
            app.FigureS9S10Button = uibutton(app.UIFigure, 'push');
            app.FigureS9S10Button.ButtonPushedFcn = createCallbackFcn(app, @FigureS9S10ButtonPushed, true);
            app.FigureS9S10Button.Text = 'Figure S9&S10';
            app.FigureS11Button = uibutton(app.UIFigure, 'push');
            app.FigureS11Button.ButtonPushedFcn = createCallbackFcn(app, @FigureS11ButtonPushed, true);
            app.FigureS11Button.Text = 'Figure S11';
            app.FigureS12Button = uibutton(app.UIFigure, 'push');
            app.FigureS12Button.ButtonPushedFcn = createCallbackFcn(app, @FigureS12ButtonPushed, true);
            app.FigureS12Button.Text = 'Figure S12';
            app.MakeAllButton = uibutton(app.UIFigure, 'push');
            app.MakeAllButton.ButtonPushedFcn = createCallbackFcn(app, @MakeAllButtonPushed, true);
            app.MakeAllButton.Text = 'Make All';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Make_Figures_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end